classdef Fase1MYKI_APP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        UITable                      matlab.ui.control.Table
        InterrogaCyberGloveButton    matlab.ui.control.Button
        PortaListBox                 matlab.ui.control.ListBox
        PortaListBoxLabel            matlab.ui.control.Label
        InizializzaCyberGloveButton  matlab.ui.control.Button
        TextArea                     matlab.ui.control.TextArea
        InizializzaTactorsButton     matlab.ui.control.Button
    end

    
    properties (Access = private)
        Property % Description                                             devo inserire le properties!! come glove e cose!!!
    end
    
    methods (Access = private)
                                                            
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            oggi = string(datetime('now','Format','dd-MM-yy'));
            percorso = strcat('Fase1_',oggi);
            if ~exist(percorso, 'dir')
                mkdir(percorso);
                addpath(percorso);
                disp("Creata cartella datata per oggi: "+oggi);
            end
        end

        % Button pushed function: InizializzaTactorsButton
        function InizializzaTactorsButtonPushed(app, event)
            ti = TactorInterfaceML;

            numFound = ti.Discover;

            if (numFound > 0)
                for devIndex = 0:(numFound-1)
                    [dev, type] = ti.GetDevice(devIndex);
                    if dev == "\\.\COM13"
                        ti.Connect(dev, type);
                    end
                end
            end

            app.TextArea.Value = strcat("Connesso, ", dev, " e ", type);
        end

        % Button pushed function: InizializzaCyberGloveButton
        function InizializzaCyberGloveButtonPushed(app, event)
            serials = serialportlist;
            app.PortaListBox.Items = serials;
        end

        % Clicked callback: PortaListBox
        function PortaListBoxClicked(app, event)
            item = event.InteractionInformation.Item;
            glove = Cyberglove(strcat("COM", num2str(item)));
        end

        % Button pushed function: InterrogaCyberGloveButton
        function InterrogaCyberGloveButtonPushed(app, event)
            if ~exist("DataTags","var")
                load("DataTags.mat","DataTags")
            end

            write(glove.serial_p, 'G', 'uint8');
            data = read(glove.serial_p, 20, 'uint8');

            if exist("tab","var")
                tab = [tab; data];
            else
                tab = [DataTags; data];                                     
            end

            app.UITable.ColumnName = DataTags;
            app.UITable.Data = tab;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.InizializzaTactorsButton = uibutton(app.UIFigure, 'push');
            app.InizializzaTactorsButton.ButtonPushedFcn = createCallbackFcn(app, @InizializzaTactorsButtonPushed, true);
            app.InizializzaTactorsButton.Text = {'Inizializza'; 'Tactors'};
            app.TextArea = uitextarea(app.UIFigure);
            app.InizializzaCyberGloveButton = uibutton(app.UIFigure, 'push');
            app.InizializzaCyberGloveButton.ButtonPushedFcn = createCallbackFcn(app, @InizializzaCyberGloveButtonPushed, true);
            app.InizializzaCyberGloveButton.Text = {'Inizializza'; 'CyberGlove'};
            app.PortaListBoxLabel = uilabel(app.UIFigure);
            app.PortaListBoxLabel.Text = 'Porta';
            app.PortaListBox = uilistbox(app.UIFigure);
            app.PortaListBox.Items = {};
            app.PortaListBox.ClickedFcn = createCallbackFcn(app, @PortaListBoxClicked, true);
            app.PortaListBox.Value = {};
            app.InterrogaCyberGloveButton = uibutton(app.UIFigure, 'push');
            app.InterrogaCyberGloveButton.ButtonPushedFcn = createCallbackFcn(app, @InterrogaCyberGloveButtonPushed, true);
            app.InterrogaCyberGloveButton.Text = {'Interroga'; 'CyberGlove'};
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Fase1MYKI_APP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end